classdef send_ble_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Knob3       matlab.ui.control.Knob
        Knob3Label  matlab.ui.control.Label
        Knob2       matlab.ui.control.Knob
        Knob2Label  matlab.ui.control.Label
        Knob        matlab.ui.control.Knob
        KnobLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        muscleOneReading;   % int ∈[0,127]: voltage at A0
        muscleTwoReading;   % int ∈[0,127]: voltage at A1
        muscleThreeReading; % int ∈[0,127]: voltage at A2
        
        muscleOneEnabled;   % bool: is muscle one being stimulated?
        muscleTwoEnabled;   % bool: is muscle two being stimulated?
        muscleThreeEnabled; % bool: is muscle three being stimulated?
    end
    
    methods (Access = private)
        
        function results = muscleOneHandler(app)
            
        end
        
        function results = loop(app)
            while 1
                %% Grab value from serial monitor
                
                %% Parse input based on first letter
                
                %% Send to appropriate handler


            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialize bluetooth
            
            %% Initalize serial monitor
            
            %% Initialize properties
            
            %% Write 0 to muscleVoltage charactericts, starting w/ muscle 3
            
            loop(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'continuous');
            app.Knob3Label = uilabel(app.UIFigure);
            app.Knob3Label.Text = 'Knob3';
            app.Knob3 = uiknob(app.UIFigure, 'continuous');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end