classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        SliderLabel   matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        Slider2Label  matlab.ui.control.Label
        Slider2       matlab.ui.control.Slider
        RightPanel    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function FrequencyChanged(app, event)
            % value = app.Slider.Value;
            value=round(app.Slider.Value);
            app.Slider.Value=value;
            if value == 1
                app.Slider2.Limits = [1 3];
                app.Slider2.MajorTicks = [1 2 3];
            elseif value == 2 
                app.Slider2.Limits = [1 6];
                app.Slider2.MajorTicks = [1 2 3 4 5 6];
            elseif value == 3
                app.Slider2.Limits = [1 9];
                app.Slider2.MajorTicks = [1 2 3 4 5 6 7 8 9];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [1 3];
            app.Slider.MajorTicks = [1 2 3];
            app.Slider.MajorTickLabels = {'30.64', '61.04', '122.08', ''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @FrequencyChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.Slider2Label = uilabel(app.LeftPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.LeftPanel);
            app.Slider2.Limits = [1 3];
            app.Slider2.MajorTicks = [1 2 3];
            app.Slider2.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end