classdef send_ble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Muscle3Switch       matlab.ui.control.Switch
        Muscle3SwitchLabel  matlab.ui.control.Label
        Muscle2Switch       matlab.ui.control.Switch
        Muscle2SwitchLabel  matlab.ui.control.Label
        Knob_3              matlab.ui.control.Knob
        Knob_2              matlab.ui.control.Knob
        Knob                matlab.ui.control.Knob
        Muscle1Switch       matlab.ui.control.Switch
        Muscle1SwitchLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        iot;
        
        muscleOneToggle;
        muscleTwoToggle;
        muscleThreeToggle;
        
        muscleOneVoltage;
        muscleTwoVoltage;
        muscleThreeVoltage;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc; clear; 
        
        addpath(genpath("/Users/shanewalsh/Documents/capstone/blelib"))
            
        bleAddress = "98B22EEE-F9CA-420E-9F0B-7976E1D0D356";
        app.iot = ble(bleAddress);
        
        muscleToggleServiceUUID = "19B10000-E8F2-537E-4F6C-D104768A1214";
        app.muscleOneToggle = characteristic(app.iot,muscleToggleServiceUUID,"00A1");
        app.muscleTwoToggle = characteristic(app.iot,muscleToggleServiceUUID,"00A2");
        app.muscleThreeToggle = characteristic(app.iot,muscleToggleServiceUUID,"00A3");
        
        muscleVoltageServiceUUID = "19B10000-E8F2-537E-4F6C-D204768A1214";
        app.muscleOneVoltage = characteristic(app.iot,muscleVoltageServiceUUID,"00B1");
        app.muscleTwoVoltage = characteristic(app.iot,muscleVoltageServiceUUID,"00B2");
        app.muscleThreeVoltage = characteristic(app.iot,muscleVoltageServiceUUID,"00B3");
        
        write(app.muscleOneVoltage, 128);
            
        end

        % Value changed function: Muscle1Switch
        function Muscle1SwitchValueChanged(app, event)
            value = uint8(app.Muscle1Switch.Value);
            write(app.muscleOneToggle, value);
        end

        % Value changed function: Muscle2Switch
        function Muscle2SwitchValueChanged(app, event)
            value = uint8(app.Muscle2Switch.Value);
            write(app.muscleTwoToggle, value);
        end

        % Value changed function: Muscle3Switch
        function Muscle3SwitchValueChanged(app, event)
            value = uint8(app.Muscle3Switch.Value);
            write(app.muscleThreeToggle, value);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = uint8(round(app.Knob.Value));
            which -all write
            write(app.muscleOneVoltage, 128);
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = uint8(round(app.Knob_2.Value));
            write(app.muscleTwoVoltage, value);
        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            value = uint8(round(app.Knob_3.Value));
            write(app.muscleThreeVoltage, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Muscle1SwitchLabel = uilabel(app.UIFigure);
            app.Muscle1SwitchLabel.Text = 'Muscle 1';
            app.Muscle1Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle1Switch.ItemsData = {'0', '1'};
            app.Muscle1Switch.ValueChangedFcn = createCallbackFcn(app, @Muscle1SwitchValueChanged, true);
            app.Muscle1Switch.Value = '0';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 128];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Knob_2.Limits = [0 128];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_3 = uiknob(app.UIFigure, 'continuous');
            app.Knob_3.Limits = [0 128];
            app.Knob_3.MajorTickLabels = {'0', '16', '32', '48', '64', '80', '96', '112', '128'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Muscle2SwitchLabel = uilabel(app.UIFigure);
            app.Muscle2SwitchLabel.Text = 'Muscle 2';
            app.Muscle2Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle2Switch.ItemsData = {'0', '1'};
            app.Muscle2Switch.ValueChangedFcn = createCallbackFcn(app, @Muscle2SwitchValueChanged, true);
            app.Muscle2Switch.Value = '0';
            app.Muscle3SwitchLabel = uilabel(app.UIFigure);
            app.Muscle3SwitchLabel.Text = 'Muscle 3';
            app.Muscle3Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle3Switch.ItemsData = {'0', '1'};
            app.Muscle3Switch.ValueChangedFcn = createCallbackFcn(app, @Muscle3SwitchValueChanged, true);
            app.Muscle3Switch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end