classdef send_ble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InitializeTab               matlab.ui.container.Tab
        SerialAddressDropDown       matlab.ui.control.DropDown
        SerialAddressDropDownLabel  matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        BLEAddressDropDown          matlab.ui.control.DropDown
        BLEAddressDropDownLabel     matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        EnableControlPanelButton    matlab.ui.control.Button
        InitializeBLEButton         matlab.ui.control.Button
        ControlPanelTab             matlab.ui.container.Tab
        Knob_3                      matlab.ui.control.Knob
        Lamp_5                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Knob_2                      matlab.ui.control.Knob
        Knob                        matlab.ui.control.Knob
    end


    properties (Access = public)
        
        serialAddress;
        bleAddress;
        
        %% StartButtonPushed properties
        serialMonitor;      % Arduino Mini ("Arduino 1") serial monitor
        iot;                % Arduino Nano ("Arduino 2") BLE object
        
        muscleOneReading = 0;   % int ∈[0,1]: voltage at A0
        muscleTwoReading = 0;   % int ∈[0,1]: voltage at A1
        muscleThreeReading = 0; % int ∈[0,1]: voltage at A2
        
        muscleOneVoltage = 0;   % int ∈{0, 33 to 39}: voltage stimulating muscle one 
        muscleTwoVoltage = 0;   % int ∈{0, 33 to 39}: voltage stimulating muscle two
        muscleThreeVoltage = 0; % int ∈{0, 33 to 39}: voltage stimulating muscle three
        
        muscleOneVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleTwoVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleThreeVoltageChar; % characteristic object - writing to this stimulates muscle one
        
    end
    
    methods (Access = private)

       function muscleOneHandler(app, voltageReading)
            knobValue = round(app.Knob.Value);
            
            % If necessary, adjust voltage to muscle one
            if (voltageReading == 0) && (app.muscleOneVoltage ~= 0)
                write(app.muscleOneVoltageChar, 0);
                app.muscleOneVoltage = 0;
                app.Lamp_3.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleOneVoltage ~= knobValue)
                write(app.muscleOneVoltageChar, knobValue)
                app.muscleOneVoltage = knobValue;
                app.Lamp_3.Color = [0.39 0.83 0.07];
            end
       end
       
       function muscleTwoHandler(app, voltageReading)
            knobValue = round(app.Knob_2.Value);
            
            % If necessary, adjust voltage to muscle two
            if (voltageReading == 0) && (app.muscleTwoVoltage ~= 0)
                write(app.muscleTwoVoltageChar, 0);
                app.muscleTwoVoltage = 0;
                app.Lamp_4.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleTwoVoltage ~= knobValue)
                write(app.muscleTwoVoltageChar, knobValue)
                app.muscleTwoVoltage = knobValue;
                app.Lamp_4.Color = [0.39 0.83 0.07];
            end
       end
       
       function muscleThreeHandler(app, voltageReading)
            knobValue = round(app.Knob_2.Value);
            
            % If necessary, adjust voltage to muscle three
            if (voltageReading == 0) && (app.muscleThreeVoltage ~= 0)
                write(app.muscleThreeVoltageChar, 0);
                app.muscleThreeVoltage = 0;
                app.Lamp_5.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleThreeVoltage ~= knobValue)
                write(app.muscleThreeVoltageChar, knobValue)
                app.muscleThreeVoltage = knobValue;
                app.Lamp_5.Color = [0.39 0.83 0.07];
            end
       end

        
        function loop(app)
            % Retrieve encoded value from serial monitor
            serialMonitorValue = convertStringsToChars(app.serialMonitor.readline);

            % Call each handler with either a 0 or 1
            if numel(serialMonitorValue) == 3
                muscleOneHandler(app, str2double(serialMonitorValue(1)));
                muscleTwoHandler(app, str2double(serialMonitorValue(2)));
                muscleThreeHandler(app, str2double(serialMonitorValue(3)));
            end
            
            % Flush serial monitor so next line can be retrieved
            app.serialMonitor.flush;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear; instrreset;
            
        end

        % Button pushed function: InitializeBLEButton
        function InitializeBLEButtonPushed(app, event)
            % Initalize serial monitor  
            app.serialMonitor = serialport(app.serialAddress, 9600);
            
            % Initialize ble object
            app.iot = ble(app.bleAddress);
        
            % Initialize ble characteristics
            muscleVoltageServiceUUID = "19B10000-E8F2-537E-4F6C-D204768A1214";
            app.muscleOneVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B1");
            app.muscleTwoVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B2");
            app.muscleThreeVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B3");
            
            % Write 0 to muscle voltage charactericts
            write(app.muscleOneVoltageChar, 0);
            write(app.muscleTwoVoltageChar, 0);
            write(app.muscleThreeVoltageChar, 0);
     
            % Indicate 'ready' status of BLE object
            app.Lamp_2.Color = [0.39 0.83 0.07];
            
            % Unlock start button
            app.EnableControlPanelButton.BackgroundColor = [0.96 0.96 0.96];
            app.EnableControlPanelButton.FontColor = [0 0 0];   
        end

        % Button pushed function: EnableControlPanelButton
        function EnableControlPanelButtonPushed(app, event)
            % Break off into main control flow
            app.Lamp.Color = [0.39 0.83 0.07];
            while 1
                loop(app)
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            bleTable = blelist;
            app.BLEAddressDropDown.Items = table2array(bleTable(:,3));
        end

        % Value changed function: BLEAddressDropDown
        function BLEAddressDropDownValueChanged(app, event)
            value = app.BLEAddressDropDown.Value;
            app.bleAddress = value;
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.SerialAddressDropDown.Items = seriallist; 
            % Unlock 'initalize BLE' button
            app.InitializeBLEButton.BackgroundColor = [0.96 0.96 0.96];
            app.InitializeBLEButton.FontColor = [0 0 0]; 
        end

        % Value changed function: SerialAddressDropDown
        function SerialAddressDropDownValueChanged(app, event)
            value = app.SerialAddressDropDown.Value;
            app.serialAddress = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.InitializeBLEButton = uibutton(app.InitializeTab, 'push');
            app.InitializeBLEButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeBLEButtonPushed, true);
            app.InitializeBLEButton.Text = 'Initialize BLE';
            app.EnableControlPanelButton = uibutton(app.InitializeTab, 'push');
            app.EnableControlPanelButton.ButtonPushedFcn = createCallbackFcn(app, @EnableControlPanelButtonPushed, true);
            app.EnableControlPanelButton.Text = 'Enable Control Panel';
            app.Lamp = uilamp(app.InitializeTab);
            app.Lamp_2 = uilamp(app.InitializeTab);
            app.BLEAddressDropDownLabel = uilabel(app.InitializeTab);
            app.BLEAddressDropDownLabel.Text = 'BLE Address';
            app.BLEAddressDropDown = uidropdown(app.InitializeTab);
            app.BLEAddressDropDown.Items = {};
            app.BLEAddressDropDown.Editable = 'on';
            app.BLEAddressDropDown.ValueChangedFcn = createCallbackFcn(app, @BLEAddressDropDownValueChanged, true);
            app.BLEAddressDropDown.Value = {};
            app.Image = uiimage(app.InitializeTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'refresh.png';
            app.Image_2 = uiimage(app.InitializeTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'refresh.png';
            app.SerialAddressDropDownLabel = uilabel(app.InitializeTab);
            app.SerialAddressDropDownLabel.Text = {'Serial Address'; ''};
            app.SerialAddressDropDown = uidropdown(app.InitializeTab);
            app.SerialAddressDropDown.Items = {};
            app.SerialAddressDropDown.Editable = 'on';
            app.SerialAddressDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialAddressDropDownValueChanged, true);
            app.SerialAddressDropDown.Value = {};
            app.ControlPanelTab = uitab(app.TabGroup);
            app.ControlPanelTab.Title = 'Control Panel';
            app.Knob = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob.Limits = [33 39];
            app.Knob.MajorTicks = [33 35 37 39];
            app.Knob.MinorTicks = [34 36 38];
            app.Knob.Value = 33;
            app.Knob_2 = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob_2.Limits = [33 39];
            app.Knob_2.MajorTicks = [33 35 37 39];
            app.Knob_2.MinorTicks = [34 36 38];
            app.Knob_2.Value = 33;
            app.Lamp_3 = uilamp(app.ControlPanelTab);
            app.Lamp_4 = uilamp(app.ControlPanelTab);
            app.Lamp_5 = uilamp(app.ControlPanelTab);
            app.Knob_3 = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob_3.Limits = [33 39];
            app.Knob_3.MajorTicks = [33 35 37 39];
            app.Knob_3.MinorTicks = [34 36 38];
            app.Knob_3.Value = 33;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end