classdef send_ble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Lamp                matlab.ui.control.Lamp
        Label               matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        Muscle3Switch       matlab.ui.control.Switch
        Muscle3SwitchLabel  matlab.ui.control.Label
        Muscle2Switch       matlab.ui.control.Switch
        Muscle2SwitchLabel  matlab.ui.control.Label
        Knob_3              matlab.ui.control.Knob
        Knob_2              matlab.ui.control.Knob
        Knob                matlab.ui.control.Knob
        Muscle1Switch       matlab.ui.control.Switch
        Muscle1SwitchLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        %% startupFcn properties
        muscleOneThreshold;     % int ∈[0,1023]: min. muscleOneReading to enable muscle one
        muscleTwoThreshold;     % int ∈[0,1023]: min. muscleTwoReading to enable muscle two
        muscleThreeThreshold;   % int ∈[0,1023]: min. muscleThreeReading to enable muscle three
        
        logsize;
        muscleOneReadingLog;
        muscleTwoReadingLog;
        muscleThreeReadingLog;
        
        
        %% StartButtonPushed properties
        serial;
        iot;                % Arduino Nano BLE object
        
        muscleOneReading;   % int ∈[0,1023]: voltage at A0
        muscleTwoReading;   % int ∈[0,1023]: voltage at A1
        muscleThreeReading; % int ∈[0,1023]: voltage at A2
        
        muscleOneEnabled;   % bool: is muscle one being stimulated?
        muscleTwoEnabled;   % bool: is muscle two being stimulated?
        muscleThreeEnabled; % bool: is muscle three being stimulated?
        
        muscleOneVoltage;   % int ∈[0,127]: voltage to muscleOne when muscleOneEnabled
        muscleTwoVoltage;   % int ∈[0,127]: voltage to muscleTwo when muscleTwoEnabled
        muscleThreeVoltage; % int ∈[0,127]: voltage to muscleThree when musclThreeEnabled
        
        muscleOneChar;
        muscleTwoChar;
        muscleThreeChar;
        
    end
    
    methods (Access = private)
        
        function log = createLog(app)
            log = List();
            
            % Fill log to capacity with zeroes
            for i = 1:app.logsize
                log.Append(i);
            end
            
            log.Delete(0);
            log.Append(999);
            fprintf('worked')
        end
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear; 
            
            % Declare thresholds
            app.muscleOneThreshold = 1000;
            app.muscleTwoThreshold = 1000;
            app.muscleThreeThreshold = 1000;
            
            app.logsize = 100;
         
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = uint8(round(app.Knob.Value));
            write(app.muscleOneChar, value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Initalize serial monitor  
            
            % Initialize ble object
            bleAddress = "98B22EEE-F9CA-420E-9F0B-7976E1D0D356";
            app.iot = ble(bleAddress);
        
            % Initialize ble characteristics
            muscleVoltageServiceUUID = "19B10000-E8F2-537E-4F6C-D204768A1214";
            app.muscleOneChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B1");
            app.muscleTwoChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B2");
            app.muscleThreeChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B3");
            
            % Write 0 to muscle voltage charactericts
            write(app.muscleThreeChar, 0);
            write(app.muscleTwoChar, 0);
            write(app.muscleOneChar, 0);
            
            % Initialize properties
            app.muscleOneReadingLog = createLog(app);


            app.Lamp.Color = [0.00 1.00 0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Muscle1SwitchLabel = uilabel(app.UIFigure);
            app.Muscle1SwitchLabel.Text = 'Muscle 1';
            app.Muscle1Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle1Switch.ItemsData = {'0', '1'};
            app.Muscle1Switch.Value = '0';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 128];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Knob_2.Limits = [0 128];
            app.Knob_3 = uiknob(app.UIFigure, 'continuous');
            app.Knob_3.Limits = [0 128];
            app.Knob_3.MajorTickLabels = {'0', '16', '32', '48', '64', '80', '96', '112', '128'};
            app.Muscle2SwitchLabel = uilabel(app.UIFigure);
            app.Muscle2SwitchLabel.Text = 'Muscle 2';
            app.Muscle2Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle2Switch.ItemsData = {'0', '1'};
            app.Muscle2Switch.Value = '0';
            app.Muscle3SwitchLabel = uilabel(app.UIFigure);
            app.Muscle3SwitchLabel.Text = 'Muscle 3';
            app.Muscle3Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle3Switch.ItemsData = {'0', '1'};
            app.Muscle3Switch.Value = '0';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end